classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Menu          matlab.ui.container.Menu
        Menu_2        matlab.ui.container.Menu
        UITable       matlab.ui.control.Table
        Button        matlab.ui.control.Button
        Label         matlab.ui.control.Label
        Edit_Name     matlab.ui.control.EditField
        Label_2       matlab.ui.control.Label
        Edit_Age      matlab.ui.control.NumericEditField
        Button_2      matlab.ui.control.Button
        Label_4       matlab.ui.control.Label
        Edit_Address  matlab.ui.control.EditField
        Label_5       matlab.ui.control.Label
        Edit_ID       matlab.ui.control.NumericEditField
        Label_6       matlab.ui.control.Label
        Edit_Salary   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Property % Description
        id
        name
        age
        address
        salary
        data_all
    end
    
    methods (Access = private)
        
        function results = SendtoSql(app)
            db_name        = 'postgres';
            db_user_name   = 'postgres';
            db_user_pass   = 'postgre';
            db_server_ip   = 'localhost';
            db_server_port = '5432';
            url            = strcat('jdbc:postgresql://', db_server_ip, ':', db_server_port, '/');
            connection            = database(db_name, db_user_name, db_user_pass, 'org.postgresql.Driver', url);
%             Insert_command= "INSERT INTO COMPANY (ID,NAME,AGE,ADDRESS,SALARY,JOIN_DATE) VALUES (4, 'Mark', 25, 'Rich-Mond ', 65000.00, '2007-12-13' ), (5, 'David', 27, 'Texas', 85000.00, '2007-12-13');"
            Insert_command= "INSERT INTO COMPANY (ID,NAME,AGE,ADDRESS,SALARY) VALUES (0_IDXXX, '0_NameXXX', 0_AgeXXX, '0_AddressXXX', 0_SalaryXXX);"
            temp = strrep(Insert_command,'0_IDXXX',num2str(app.id));
            temp = strrep(temp,'0_NameXXX',num2str(app.name));
            temp = strrep(temp,'0_AgeXXX',num2str(app.age));
            temp = strrep(temp,'0_AddressXXX',num2str(app.address));
            temp = strrep(temp,'0_SalaryXXX',num2str(app.salary));
            command=temp;
            exec(connection,command);
        end
    end
    

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            db_name        = 'postgres';
            db_user_name   = 'postgres';
            db_user_pass   = 'postgre';
            db_server_ip   = 'localhost';
            db_server_port = '5432';
            url            = strcat('jdbc:postgresql://', db_server_ip, ':', db_server_port, '/');
            connection            = database(db_name, db_user_name, db_user_pass, 'org.postgresql.Driver', url);
            command =  "SELECT * FROM company;";
            cursor = exec(connection,command);
            row=fetch(cursor);
            app.data_all = row.Data;
%             a = rand(100,4);
            app.UITable.Data =row.Data;
            % AND
            % 找出 AGE(年龄) 字段大于等于 25，并且 SALARY(薪资) 字段大于等于 65000 的数据：
            % command = "SELECT * FROM COMPANY WHERE AGE >= 25 AND SALARY >= 65000;";
            
            % OR 
            % 找出 AGE(年龄) 字段大于等于 25，或者 SALARY(薪资) 字段大于等于 65000 的数据：
            % command = "SELECT * FROM COMPANY WHERE AGE >= 25 OR SALARY >= 65000;";
            
            %以下使用了布尔表达式（SALARY=10000）来查询数据
            % SELECT * FROM COMPANY WHERE SALARY = 10000
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            app.UITable.Visible='on';
            app.Button.Visible='on';
            app.Edit_Name.Visible='off';
            app.Edit_Age.Visible='off';
            app.Edit_Address.Visible='off';
            app.Edit_ID.Visible='off';
            app.Edit_Salary.Visible='off';
            app.Label.Visible='off';
            app.Label_2.Visible='off';
            app.Label_4.Visible='off';
            app.Label_5.Visible='off';
            app.Label_6.Visible='off';
            app.Button_2.Visible='off';
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
             app.UITable.Visible='off';
             app.Button.Visible='off';
             app.Edit_Name.Visible='on';
             app.Edit_Age.Visible='on';
             app.Edit_Address.Visible='on';
             app.Edit_ID.Visible='on';
             app.Edit_Salary.Visible='on';
             app.Label.Visible='on';
             app.Label_2.Visible='on';
             app.Label_4.Visible='on';
             app.Label_5.Visible='on';
             app.Label_6.Visible='on';
             app.Button_2.Visible='on';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.id = app.Edit_ID.Value;
            app.name = app.Edit_Name.Value;
            app.age = app.Edit_Age.Value;
            app.address = app.Edit_Address.Value;
            app.salary = app.Edit_Salary.Value;
            SendtoSql(app);
            msgbox("录入成功！");
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            msgbox("hello");
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
%              msgbox("hell11o");
%             MainMenu = uicontexmenu();
%             DeleteMenu    = uimenu(MainMenu,'Text','删除功能模块','Callback',@deletefun);
%             ReNameMenu    = uimenu(MainMenu,'Text','重命名功能模块','Callback',@renamefun);
%             AddModuleMenu = uimenu(MainMenu,'Text','添加检测模块','Callback',@addModulefun)
            id_temp = num2str(app.data_all{indices(1,1),1}); % 寻找点击记录的ID号
%             h0 = context(id_temp);
             context(id_temp);
              
% set(h0,'unit','normalized','color','default',...
%     'position',[0.1 0.1 0.8 0.8],'menu','none',...
%     'numbertitl','off','name','GUI 生成菜单');
% h = uicontextmenu;       %创建右键菜单
% set(h0,'uicontextmenu',h);%将右键菜单和窗口相联系设置窗口uicontextmenu属性值是h
% h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% % h1 = uimenu(h,'label','一级菜单');
% %每执行一次就会生成一个菜单选项
% %%
% h2 = uimenu(h1,'label','二级菜单');
% h3 = uimenu(h2,'label','三级菜单');
% h4 = uimenu(h3,'label','四级菜单');
% h5 = uimenu(h4,'label','五级菜单');

        end

        % Callback function
        function UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            msgbox("hell1111122o");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '录入界面';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '查询界面';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'员工工号'; '员工姓名'; '员工年龄'; '员工住址'; '员工薪资'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Visible = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Visible = 'off';
            app.Button.Text = '筛选';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '员工姓名：';
            app.Edit_Name = uieditfield(app.UIFigure, 'text');
            app.Edit_Name.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '员工年龄：';
            app.Edit_Age = uieditfield(app.UIFigure, 'numeric');
            app.Edit_Age.Visible = 'off';
            app.Edit_Age.Value = -1;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Visible = 'off';
            app.Button_2.Text = '录入';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '员工地址：';
            app.Edit_Address = uieditfield(app.UIFigure, 'text');
            app.Edit_Address.Visible = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '员工工号：';
            app.Edit_ID = uieditfield(app.UIFigure, 'numeric');
            app.Edit_ID.ValueDisplayFormat = '%100.0f';
            app.Edit_ID.Visible = 'off';
            app.Edit_ID.Value = -1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '员工薪水';
            app.Edit_Salary = uieditfield(app.UIFigure, 'numeric');
            app.Edit_Salary.Visible = 'off';
            app.Edit_Salary.Value = -1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end